classdef MAX30001_ICM20948_config_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RegistersButtonGroup          matlab.ui.container.ButtonGroup
        EN_INT1Button                 matlab.ui.control.ToggleButton
        EN_INT2Button                 matlab.ui.control.ToggleButton
        MNGR_INTButton                matlab.ui.control.ToggleButton
        CNFG_GENButton                matlab.ui.control.ToggleButton
        MNGR_DYNButton                matlab.ui.control.ToggleButton
        CNFG_CALButton                matlab.ui.control.ToggleButton
        CNFG_EMUXButton               matlab.ui.control.ToggleButton
        CNFG_ECGButton                matlab.ui.control.ToggleButton
        CNFG_BMUXButton               matlab.ui.control.ToggleButton
        CNFG_BIOZButton               matlab.ui.control.ToggleButton
        CNFG_PACEButton               matlab.ui.control.ToggleButton
        CNFG_RTOR1Button              matlab.ui.control.ToggleButton
        CNFG_RTOR2Button              matlab.ui.control.ToggleButton
        ICselectButtonGroup           matlab.ui.container.ButtonGroup
        MAX30001Button                matlab.ui.control.RadioButton
        ICM20948Button                matlab.ui.control.RadioButton
        ICM20948UserbankselectButtonGroup  matlab.ui.container.ButtonGroup
        USERBANK0Button               matlab.ui.control.ToggleButton
        USERBANK1Button               matlab.ui.control.ToggleButton
        USERBANK2Button               matlab.ui.control.ToggleButton
        USERBANK3Button               matlab.ui.control.ToggleButton
        RegistersListBoxLabel         matlab.ui.control.Label
        RegistersListBox              matlab.ui.control.ListBox
        RightPanel                    matlab.ui.container.Panel
        HardwarePanel                 matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        Refresh                       matlab.ui.control.Button
        PortLabel                     matlab.ui.control.Label
        PortList                      matlab.ui.control.DropDown
        BCon                          matlab.ui.control.Button
        BDiscon                       matlab.ui.control.Button
        QuickcommandsDropDownLabel    matlab.ui.control.Label
        QuickcommandsDropDown         matlab.ui.control.DropDown
        SendButton                    matlab.ui.control.Button
        Bits2419ListBoxLabel          matlab.ui.control.Label
        Bits2316ListBox               matlab.ui.control.ListBox
        CheckBox24                    matlab.ui.control.CheckBox
        CheckBox23                    matlab.ui.control.CheckBox
        CheckBox22                    matlab.ui.control.CheckBox
        NumericEditFieldLabel         matlab.ui.control.Label
        NumericEditField              matlab.ui.control.NumericEditField
        HexEditFieldLabel             matlab.ui.control.Label
        HexEditField                  matlab.ui.control.EditField
        SendHex                       matlab.ui.control.Button
        CheckBox21                    matlab.ui.control.CheckBox
        CheckBox20                    matlab.ui.control.CheckBox
        CheckBox19                    matlab.ui.control.CheckBox
        CheckBox18                    matlab.ui.control.CheckBox
        CheckBox17                    matlab.ui.control.CheckBox
        Bits158ListBox                matlab.ui.control.ListBox
        Bits158ListBoxLabel           matlab.ui.control.Label
        Bits70ListBox                 matlab.ui.control.ListBox
        Bits70ListBoxLabe             matlab.ui.control.Label
        CheckBox16                    matlab.ui.control.CheckBox
        CheckBox15                    matlab.ui.control.CheckBox
        CheckBox14                    matlab.ui.control.CheckBox
        CheckBox13                    matlab.ui.control.CheckBox
        CheckBox12                    matlab.ui.control.CheckBox
        CheckBox11                    matlab.ui.control.CheckBox
        CheckBox10                    matlab.ui.control.CheckBox
        CheckBox9                     matlab.ui.control.CheckBox
        CheckBox8                     matlab.ui.control.CheckBox
        CheckBox7                     matlab.ui.control.CheckBox
        CheckBox6                     matlab.ui.control.CheckBox
        CheckBox5                     matlab.ui.control.CheckBox
        CheckBox4                     matlab.ui.control.CheckBox
        CheckBox3                     matlab.ui.control.CheckBox
        CheckBox2                     matlab.ui.control.CheckBox
        CheckBox1                     matlab.ui.control.CheckBox
        ReceivedMessageTextAreaLabel  matlab.ui.control.Label
        ReceivedMessageTextArea       matlab.ui.control.TextArea
        ConnectionstatusLampLabel     matlab.ui.control.Label
        ConnectionstatusLamp          matlab.ui.control.Lamp
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
           s % serial port
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
        stopasync(app.s)
        fclose(app.s);
        %if status==0
               app.ConnectionstatusLamp.Enable=0;
         %   end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            app.s = serial(app.PortList.Value);
            fopen(app.s);
           % fileID=fopen(app.s);
            %if fileID~=-1
              app.ConnectionstatusLamp.Enable=1;
           % end
        end

        % Value changed function: QuickcommandsDropDown
        function command(app, event)
            Value = app.QuickcommandsDropDown.Value;
            
        end

        % Button pushed function: SendButton
        function SendB(app, event)
       % app1.s = serial(app.PortList.Value); 
        command_data=app.QuickcommandsDropDown.Value;
        selectedButton = app.ICselectButtonGroup.SelectedObject;
        mode_select=0;    
        if selectedButton == app.MAX30001Button
            if command_data=="SW reset"
                datatosend="MAX_SW_RESET 000000";
            end
            if command_data=="Synchronize"
                datatosend="MAX_SYNCH 000000";
            end
            if command_data=="FIFO reset"
                datatosend="MAX_FIFO_RST 000000";
            end
            if command_data=="Read STATUS register"
                datatosend="MAX_STATUS 000000";
            end
            if command_data=="Stop ECG"
                datatosend="MAX_ECG_STOP 000000";
            end
            if command_data=="Stop BIOZ"
                datatosend="MAX_BIOZ_STOP 000000";
            end
            if command_data=="Stop RtoR"
                datatosend="MAX_RTOR_STOP 000000";
            end
            if command_data=="Start ECG"
                datatosend="MAX_ECG_START 000000";
            end
            if command_data=="Start BIOZ"
                datatosend="MAX_BIOZ_START 000000";
            end
            if command_data=="Start RtoR"
                datatosend="MAX_RTOR_START 000000";
            end
            if command_data=="ECG Output Mode"
                datatosend="MODE_ECG 000000";
                mode_select=1;
            end
            if command_data=="BioZ Output Mode"
                datatosend="MODE_BIOZ 000000";
                mode_select=1;
            end
            if command_data=="RtoR Output Mode"
                datatosend="MODE_RTOR 000000";
                mode_select=1;
            end
            if command_data=="DSP Output Mode"
                datatosend="MODE_DSP 000000";
                mode_select=1;
            end
            if command_data=="BPM Output Mode"
                datatosend="MODE_BPM 000000";
                mode_select=1;
            end
        end
        
        if selectedButton == app.ICM20948Button
            if command_data=="SW reset"
                datatosend="ICM_SW_RESET 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="FIFO reset"
                datatosend="ICM_FIFO_RST 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Stop Accel"
                datatosend="ICM_ACCEL_STOP 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Stop Gyro"
                datatosend="ICM_GYRO_STOP 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Stop Magn"
                datatosend="ICM_MAGN_STOP 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Stop Temp"
                datatosend="ICM_TEMP_STOP 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Start Accel"
                datatosend="ICM_ACCEL_START 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Start Gyro"
                datatosend="ICM_GYRO_START 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Start Magn"
                datatosend="ICM_MAGN_START 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Start Temp"
                datatosend="ICM_TEMP_START 000000"; %stavit tocnu adresu i vrijednost
            end
            if command_data=="Combined Output Mode"
                datatosend="MODE_COMBINED 000000";
                mode_select=1;
            end
            if command_data=="Accel Output Mode"
                datatosend="MODE_ACCEL 000000";
                mode_select=1;
            end
            if command_data=="Gyro Output Mode"
                datatosend="MODE_GYRO 000000";
                mode_select=1;
            end
            if command_data=="Magn Output Mode"
                datatosend="MODE_MAGN 000000";
                mode_select=1;
            end
            if command_data=="Temp Output Mode"
                datatosend="MODE_TEMP 000000";
                mode_select=1;
            end
        end
        
        fprintf(app.s, datatosend);
        
        if mode_select==0
            pause(0.05);
            message=fscanf(app.s);
            app.ReceivedMessageTextArea.Value=message;
            pause(0.05);
            message=strcat(message,"              ",fscanf(app.s));
            app.ReceivedMessageTextArea.Value=message;
        end
            
        end

        % Selection changed function: RegistersButtonGroup
        function RegisterSelected(app, event)
            selectedButton = app.RegistersButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton,'EN_INT1')
                app.Bits2316ListBox.Items = {'EN_EINT', 'EN_EOVF', 'EN_FSTINT', 'EN_DCLOFFINT', 'EN_BINT','EN_BOVF','EN_BOVER','EN_BUNDR'};
                app.Bits158ListBox.Items = {'EN_BCGMON', 'EN_PINT', 'EN_POVF', 'EN_PEDGE', 'EN_LONINT','EN_RRINT','EN_SAMP','EN_PPLINT'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','INTB_TYPE[1]','INTB_TYPE[0]'};
            else if strcmp(selectedButton,'EN_INT2')
                app.Bits2316ListBox.Items = {'EN_EINT2', 'EN_EOVF2', 'EN_FSTINT2','EN_DCLOFFINT2', 'EN_BINT2','EN_BOVF2','EN_BOVER2','EN_BUNDR2'};
                app.Bits158ListBox.Items = {'EN_BCGMON2', 'EN_PINT2', 'EN_POVF2', 'EN_PEDGE2', 'EN_LONINT2','EN_RRINT2','EN_SAMP2','EN_PPLINT2'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','INTB2_TYPE[1]','INTB2_TYPE[0]'};
            else if strcmp(selectedButton,'MNGR_INT')
                app.Bits2316ListBox.Items = {'EFIT[4]', 'EFIT[3]', 'EFIT[2]','EFIT[1]', 'EFIT[0]','BFIT[2]','BFIT[1]','BFIT[0]'};
                app.Bits158ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
                app.Bits70ListBox.Items = {'-', 'CLR_FAST', 'CLR_RRINT[1]', 'CLR_RRINT[0]', 'CLR_PEDGE','CLR_SAMP','SAMP_IT[1]','SAMP_IT[0]'};
            else if strcmp(selectedButton,'MNGR_DYN')
                app.Bits2316ListBox.Items = {'FAST[1]', 'FAST[0]', 'FAST_TH[5]','FAST_TH[4]', 'FAST_TH[3]','FAST_TH[2]','FAST_TH[1]','FAST_TH[0]'};
                app.Bits158ListBox.Items = {'BLOFF_HI_IT[7]', 'BLOFF_HI_IT[6]', 'BLOFF_HI_IT[5]', 'BLOFF_HI_IT[4]', 'BLOFF_HI_IT[3]','BLOFF_HI_IT[2]','BLOFF_HI_IT[1]','BLOFF_HI_IT[0]'};
                app.Bits70ListBox.Items = {'BLOFF_LO_IT[7]', 'BLOFF_LO_IT[6]', 'BLOFF_LO_IT[5]', 'BLOFF_LO_IT[4]', 'BLOFF_LO_IT[3]','BLOFF_LO_IT[2]','BLOFF_LO_IT[1]','BLOFF_LO_IT[0]'};
            else if strcmp(selectedButton,'CNFG_GEN')
                app.Bits2316ListBox.Items = {'EN_ULP_LON[1]', 'EN_ULP_LON[0]', 'FMSTR[1]','FMSTR[0]', 'EN_ECG','EN_BIOZ','EN_PACE','-'};
                app.Bits158ListBox.Items = {'EN_BLOOF[1]', 'EN_BLOOF[0]', 'EN_DCLOFF[1]', 'EN_DCLOFF[0]', 'DCLOFF_IPOL','DCLOFF_IMAG[2]','DCLOFF_IMAG[1]','DCLOFF_IMAG[0]'};
                app.Bits70ListBox.Items = {'DCLOFF_VTH[1]', 'DCLOFF_VTH[0]', 'EN_RBIAS[1]', 'EN_RBIAS[0]', 'RBIASV[1]','RBIASV[0]','RBIASP','RBIASN'};
            else if strcmp(selectedButton,'CNFG_CAL')
                app.Bits2316ListBox.Items = {'-', 'EN_VCAL', 'VMODE','VMAG', '-','-','-','-'};
                app.Bits158ListBox.Items = {'-', 'FCAL[2]', 'FCAL[1]', 'FCAL[0]', 'FIFTY','THIGH[10]','THIGH[9]','THIGH[8]'};
                app.Bits70ListBox.Items = {'THIGH[7]','THIGH[6]','THIGH[5]', 'THIGH[4]','THIGH[3]','THIGH[2]','THIGH[1]','THIGH[0]'};
            else if strcmp(selectedButton,'CNFG_EMUX')
                app.Bits2316ListBox.Items = {'ECG_POL', '-', 'ECG_OPENP','ECG_OPENN', 'ECG_CALP_SEL[1]','ECG_CALP_SEL[0]','ECG_CALN_SEL[1]','ECG_CALN_SEL[0]'};
                app.Bits158ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
             else if strcmp(selectedButton,'CNFG_ECG')
                app.Bits2316ListBox.Items = {'ECG_RATE[1]', 'ECG_RATE[0]', '-','-', '-','-','ECG_GAIN[1]','ECG_GAIN[0]'};
                app.Bits158ListBox.Items = {'-', 'ECG_DHPF', 'ECG_DLPF[1]', 'ECG_DLPF[0]', '-','-','-','-'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
            else if strcmp(selectedButton,'CNFG_BMUX')
                app.Bits2316ListBox.Items = {'-', '-', 'BMUX_OPENP','BMUX_OPENN', 'BMUX_CALP_SEL[1]','BMUX_CALP_SEL[0]','BMUX_CALN_SEL[1]','BMUX_CALN_SEL[0]'};
                app.Bits158ListBox.Items = {'-', '-', 'BMUX_CG_MODE[1]', 'BMUX_CG_MODE[0]', 'BMUX_EN_BIST','BMUX_RNOM[2]','BMUX_RNOM[1]','BMUX_RNOM[0]'};
                app.Bits70ListBox.Items = {'-', 'BMUX_RMOD[2]', 'BMUX_RMOD[1]', 'BMUX_RMOD[0]', '-','-','BMUX_FBIST[1]','BMUX_FBIST[0]'};
            else if strcmp(selectedButton,'CNFG_BIOZ')
                app.Bits2316ListBox.Items = {'BIOZ_RATE', 'BIOZ_AHPF[2]', 'BIOZ_AHPF[1]','BIOZ_AHPF[0]', 'EXT_RBIAS','LN_BIOZ','BIOZ_GAIN[1]','BIOZ_GAIN[0]'};
                app.Bits158ListBox.Items = {'BIOZ_DHPF[1]', 'BIOZ_DHPF[0]', 'BIOZ_DLPF[1]', 'BIOZ_DLPF[0]', 'BIOZ_FCGEN[3]','BIOZ_FCGEN[2]','BIOZ_FCGEN[1]','BIOZ_FCGEN[0]'};
                app.Bits70ListBox.Items = {'BIOZ_CGMON', 'BIOZ_CGMAG[2]', 'BIOZ_CGMAG[1]', 'BIOZ_CGMAG[0]', 'BIOZ_PHOFF[3]','BIOZ_PHOFF[2]','BIOZ_PHOFF[1]','BIOZ_PHOFF[0]'};
            else if strcmp(selectedButton,'CNFG_PACE')
                app.Bits2316ListBox.Items = {'PACE_POLE', '-', '-','-', 'DIFF_OFF','PACE_GAIN[2]','PACE_GAIN[1]','PACE_GAIN[0]'};
                app.Bits158ListBox.Items = {'-', 'AOUT_LBW', 'AOUT[1]', 'AOUT[0]', '-', '-','-','-'};
                app.Bits70ListBox.Items = {'PACE_DACP[3]', 'PACE_DACP[2]', 'PACE_DACP[1]', 'PACE_DACP[0]','PACE_DACN[3]', 'PACE_DACN[2]', 'PACE_DACN[1]', 'PACE_DACN[0]'};
            else if strcmp(selectedButton,'CNFG_RTOR1')
                app.Bits2316ListBox.Items = {'WNDW[3]', 'WNDW[2]', 'WNDW[1]','WNDW[0]', 'RGAIN[3]','RGAIN[2]','RGAIN[1]','RGAIN[0]'};
                app.Bits158ListBox.Items = {'EN_ RTOR', '-', 'PAVG[1]', 'PAVG[0]', 'PTSF[3]', 'PTSF[2]','PTSF[1]','PTSF[0]'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
            else if strcmp(selectedButton,'CNFG_RTOR2')
                app.Bits2316ListBox.Items = {'-', '-', 'HOFF[5]','HOFF[4]', 'HOFF[3]','HOFF[2]','HOFF[1]','HOFF[0]'};
                app.Bits158ListBox.Items = {'-', '-', 'RAVG[1]', 'RAVG[0]', '-', 'RHSF[2]','RHSF[1]','RHSF[0]'};
                app.Bits70ListBox.Items = {'-', '-', '-', '-', '-','-','-','-'};
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
            end
        end

        % Value changed function: Bits2316ListBox
        function RegisterBitsUpdate(app, event)
            value = app.Bits2316ListBox.Value;
            
        end

        % Value changed function: CheckBox1, CheckBox10, 
        % CheckBox11, CheckBox12, CheckBox13, CheckBox14, 
        % CheckBox15, CheckBox16, CheckBox17, CheckBox18, 
        % CheckBox19, CheckBox2, CheckBox20, CheckBox21, 
        % CheckBox22, CheckBox23, CheckBox24, CheckBox3, CheckBox4, 
        % CheckBox5, CheckBox6, CheckBox7, CheckBox8, CheckBox9
        function RegisterValueUpdate(app, event)
            value24 = app.CheckBox24.Value;
            value23 = app.CheckBox23.Value;
            value22 = app.CheckBox22.Value;
            value21 = app.CheckBox21.Value;
            value20 = app.CheckBox20.Value;
            value19 = app.CheckBox19.Value;
            value18 = app.CheckBox18.Value;
            value17 = app.CheckBox17.Value;
            value16 = app.CheckBox16.Value;
            value15 = app.CheckBox15.Value;
            value14 = app.CheckBox14.Value;
            value13 = app.CheckBox13.Value;
            value12 = app.CheckBox12.Value;
            value11 = app.CheckBox11.Value;
            value10 = app.CheckBox10.Value;
            value9 = app.CheckBox9.Value;
            value8 = app.CheckBox8.Value;
            value7 = app.CheckBox7.Value;
            value6 = app.CheckBox6.Value;
            value5 = app.CheckBox5.Value;
            value4 = app.CheckBox4.Value;
            value3 = app.CheckBox3.Value;
            value2 = app.CheckBox2.Value;
            value1 = app.CheckBox1.Value;
            registervalue=0;
            if value24
                registervalue=registervalue + 2^23;
            end
            if value23
                registervalue=registervalue + 2^22;
            end
            if value22
                registervalue=registervalue + 2^21;
            end
            if value21
                registervalue=registervalue + 2^20;
            end
            if value20
                registervalue=registervalue + 2^19;
            end
            if value19
                registervalue=registervalue + 2^18;
            end
            if value18
                registervalue=registervalue + 2^17;
            end
            if value17
                registervalue=registervalue + 2^16;
            end
            if value16
                registervalue=registervalue + 2^15;
            end
            if value15
                registervalue=registervalue + 2^14;
            end
            if value14
                registervalue=registervalue + 2^13;
            end
            if value13
                registervalue=registervalue + 2^12;
            end
            if value12
                registervalue=registervalue + 2^11;
            end
            if value11
                registervalue=registervalue + 2^10;
            end
            if value10
                registervalue=registervalue + 2^9;
            end
            if value9
                registervalue=registervalue + 2^8;
            end
            if value8
                registervalue=registervalue + 2^7;
            end
            if value7
                registervalue=registervalue + 2^6;
            end
            if value6
                registervalue=registervalue + 2^5;
            end
            if value5
                registervalue=registervalue + 2^4;
            end
            if value4
                registervalue=registervalue + 2^3;
            end
            if value3
                registervalue=registervalue + 2^2;
            end
            if value2
                registervalue=registervalue + 2^1;
            end
            if value1
                registervalue=registervalue + 2^0;
            end
           app.NumericEditField.Value=registervalue;
           app.HexEditField.Value=dec2hex(registervalue,6);
        end

        % Button pushed function: SendHex
        function SendHexPressed(app, event)
            hexdata=app.HexEditField.Value;
            selectedButton = app.ICselectButtonGroup.SelectedObject;
            
            if selectedButton == app.MAX30001Button
                StringToSend=strcat("MAX_",app.RegistersButtonGroup.SelectedObject.Text, " ", hexdata);
            end
            if selectedButton == app.ICM20948Button
                StringToSend=strcat("ICM_",app.RegistersListBox.Value, " ", hexdata);
            end
            
            
            fprintf(app.s, StringToSend);
            pause(0.05);
            message=fscanf(app.s);
            app.ReceivedMessageTextArea.Value=message;
            pause(0.05);
            message=strcat(message,"              ",fscanf(app.s));
            app.ReceivedMessageTextArea.Value=message;
            pause(0.05);
            message=strcat(message,"                                 ",fscanf(app.s));
            app.ReceivedMessageTextArea.Value=message;
        end

        % Callback function
        function MessageReceived(app, event)
            changingValue = event.Value;
            message=fscanf(app.s);
            app.ReceivedMessageEditField.Value=message;
        end

        % Selection changed function: ICselectButtonGroup
        function IC_SelectionChanged(app, event)
            selectedButton = app.ICselectButtonGroup.SelectedObject;
            if selectedButton==app.ICM20948Button
                app.ICM20948UserbankselectButtonGroup.Visible = 'on';
                app.Bits158ListBox.Visible = 'off';
                app.Bits158ListBoxLabel.Visible = 'off';
                app.Bits2316ListBox.Visible = 'off';
                app.Bits2419ListBoxLabel.Visible = 'off';
                app.RegistersButtonGroup.Visible = 'off';
                app.CheckBox24.Visible = 'off';
                app.CheckBox23.Visible = 'off';
                app.CheckBox22.Visible = 'off';
                app.CheckBox21.Visible = 'off';
                app.CheckBox20.Visible = 'off';
                app.CheckBox19.Visible = 'off';
                app.CheckBox18.Visible = 'off';
                app.CheckBox17.Visible = 'off';
                app.CheckBox16.Visible = 'off';
                app.CheckBox15.Visible = 'off';
                app.CheckBox14.Visible = 'off';
                app.CheckBox13.Visible = 'off';
                app.CheckBox12.Visible = 'off';
                app.CheckBox11.Visible = 'off';
                app.CheckBox10.Visible = 'off';
                app.CheckBox9.Visible = 'off';
                app.RegistersListBox.Visible = 'on';
                app.RegistersListBoxLabel.Visible = 'on';
                app.RegistersListBox.Enable = 'on';
                app.EN_INT1Button.Enable = 'off';
                app.EN_INT2Button.Enable = 'off';
                app.Bits70ListBox.Position = [287 168 179 148];
                app.QuickcommandsDropDown.Items = {'SW reset', 'FIFO reset', 'Stop Accel', 'Stop Gyro', 'Stop Magn', 'Stop Temp', 'Start Accel', 'Start Gyro', 'Start Magn', 'Start Temp', 'Combined Output Mode', 'Accel Output Mode', 'Gyro Output Mode', 'Magn Output Mode', 'Temp Output Mode'};
            end
            if selectedButton==app.MAX30001Button
                app.ICM20948UserbankselectButtonGroup.Visible = 'off';
                app.Bits158ListBox.Visible = 'on';
                app.Bits158ListBoxLabel.Visible = 'on';
                app.Bits2316ListBox.Visible = 'on';
                app.Bits2419ListBoxLabel.Visible = 'on';
                app.RegistersButtonGroup.Visible = 'on';
                app.CheckBox24.Visible = 'on';
                app.CheckBox23.Visible = 'on';
                app.CheckBox22.Visible = 'on';
                app.CheckBox21.Visible = 'on';
                app.CheckBox20.Visible = 'on';
                app.CheckBox19.Visible = 'on';
                app.CheckBox18.Visible = 'on';
                app.CheckBox17.Visible = 'on';
                app.CheckBox16.Visible = 'on';
                app.CheckBox15.Visible = 'on';
                app.CheckBox14.Visible = 'on';
                app.CheckBox13.Visible = 'on';
                app.CheckBox12.Visible = 'on';
                app.CheckBox11.Visible = 'on';
                app.CheckBox10.Visible = 'on';
                app.CheckBox9.Visible = 'on';
                app.RegistersListBox.Visible = 'off';
                app.RegistersListBoxLabel.Visible = 'off';
                app.RegistersListBox.Enable = 'off';
                app.EN_INT1Button.Enable = 'on';
                app.EN_INT2Button.Enable = 'on';
                app.Bits70ListBox.Position = [337 168 129 148];
                app.QuickcommandsDropDown.Items = {'SW reset', 'Synchronize', 'FIFO reset', 'Read STATUS register', 'Stop ECG', 'Stop BIOZ', 'Stop RtoR', 'Start ECG', 'Start BIOZ', 'Start RtoR','ECG Output Mode','BioZ Output Mode','RtoR Output Mode','DSP Output Mode','BPM Output Mode'};
            end
        end

        % Selection changed function: 
        % ICM20948UserbankselectButtonGroup
        function UserBankChanged(app, event)
            selectedButton = app.ICM20948UserbankselectButtonGroup.SelectedObject;
            if selectedButton == app.USERBANK0Button
                app.RegistersListBox.Items={'USER_CTRL','LP_CONFIG','PWR_MGMT_1','PWR_MGMT_2','INT_PIN_CFG','INT_ENABLE','INT_ENABLE_1','INT_ENABLE_2','INT_ENABLE_3','FIFO_EN_1','FIFO_EN_2','FIFO_RST','FIFO_MODE','FIFO_R_W','FIFO_CFG'};
            end
            if selectedButton == app.USERBANK1Button
                app.RegistersListBox.Items={'SELF_TEST_X_GYRO','SELF_TEST_Y_GYRO','SELF_TEST_Z_GYRO','SELF_TEST_X_ACCEL','SELF_TEST_Y_ACCEL','SELF_TEST_Z_ACCEL','XA_OFFS_H','XA_OFFS_L','YA_OFFS_H','YA_OFFS_L','ZA_OFFS_H','ZA_OFFS_L','TIMEBASE_CORRECTION_PLL'};
            end
            if selectedButton == app.USERBANK2Button
                app.RegistersListBox.Items={'GYRO_SMPLRT_DIV','GYRO_CONFIG_1','GYRO_CONFIG_2','XG_OFFS_USRH','XG_OFFS_USRL','YG_OFFS_USRH','YG_OFFS_USRL','ZG_OFFS_USRH','ZG_OFFS_USRL','ODR_ALIGN_EN','ACCEL_SMPLRT_DIV_1','ACCEL_SMPLRT_DIV_2','ACCEL_INTEL_CTRL','ACCEL_WOM_THR','ACCEL_CONFIG','ACCEL_CONFIG_2','FSYNC_CONFIG','TEMP_CONFIG','MOD_CTRL_USR'};
            end
            if selectedButton == app.USERBANK3Button
                app.RegistersListBox.Items={'I2C_MST_ODR_CONFIG','I2C_MST_CTRL','I2C_MST_DELAY_CTRL','I2C_SLV0_ADDR','I2C_SLV0_REG','I2C_SLV0_CTRL','I2C_SLV0_DO','I2C_SLV1_ADDR','I2C_SLV1_REG','I2C_SLV1_CTRL','I2C_SLV1_DO','I2C_SLV2_ADDR','I2C_SLV2_REG','I2C_SLV2_CTRL','I2C_SLV2_DO','I2C_SLV3_ADDR','I2C_SLV3_REG','I2C_SLV3_CTRL','I2C_SLV3_DO','I2C_SLV4_ADDR','I2C_SLV4_REG','I2C_SLV4_CTRL','I2C_SLV4_DO'};
            end
        end

        % Value changed function: RegistersListBox
        function ICMRegisterSelected(app, event)
            value = app.RegistersListBox.Value;
            if strcmp(value,'WHO_AM_I')
                 app.Bits70ListBox.Items = {'WHO_AM_I[7]', 'WHO_AM_I[6]', 'WHO_AM_I[5]', 'WHO_AM_I[4]','WHO_AM_I[3]', 'WHO_AM_I[2]', 'WHO_AM_I[1]', 'WHO_AM_I[0]'};
            end
            %USER BANK 0 registers
            if strcmp(value,'USER_CTRL')
                 app.Bits70ListBox.Items = {'DMP_EN', 'FIFO_EN', 'I2C_MST_EN', 'I2C_IF_DIS','DMP_RST', 'SRAM_RST', 'I2C_MST_RST', '-'};
            end
            if strcmp(value,'LP_CONFIG')
                 app.Bits70ListBox.Items = {'-', 'I2C_MST_CYCLE', 'ACCEL_CYCLE', 'GYRO_CYCLE','-', '-', '-', '-'};
            end
            if strcmp(value,'PWR_MGMT_1')
                 app.Bits70ListBox.Items = {'DEVICE_RESET', 'SLEEP', 'LP_EN', '-','TEMP_DIS', 'CLKSEL[2]', 'CLKSEL[1]', 'CLKSEL[0]'};
            end
            if strcmp(value,'PWR_MGMT_2')
                 app.Bits70ListBox.Items = {'-', '-', 'DISABLE_ACCEL_X', 'DISABLE_ACCEL_Y','DISABLE_ACCEL_Z', 'DISABLE_GYRO_X', 'DISABLE_GYRO_Y', 'DISABLE_GYRO_Z'};
            end
            if strcmp(value,'INT_PIN_CFG')
                 app.Bits70ListBox.Items = {'INT1_ACTL', 'INT1_OPEN', 'INT1_LATCH_INT_EN', 'INT_ANYRD_2CLEAR','ACTL_FSYNC', 'FSYNC_INT_MODE_EN', 'BYPASS_EN', '-'};
            end
            if strcmp(value,'INT_ENABLE')
                 app.Bits70ListBox.Items = {'REG_WOF_EN', '-', '-', '-','WOM_INT_EN', 'PLL_RDY_EN', 'DMP_INT1_EN', 'I2C_MST_INT_EN'};
            end
            if strcmp(value,'INT_ENABLE_1')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', '-', '-', 'RAW_DATA_0_RDY_EN'};
            end
            if strcmp(value,'INT_ENABLE_2')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'FIFO_OVERFLOW_EN[4]','FIFO_OVERFLOW_EN[3]', 'FIFO_OVERFLOW_EN[2]', 'FIFO_OVERFLOW_EN[1]', 'FIFO_OVERFLOW_EN[0]'};
            end
            if strcmp(value,'INT_ENABLE_3')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'FIFO_WM_EN[4]','FIFO_WM_EN[3]', 'FIFO_WM_EN[2]', 'FIFO_WM_EN[1]', 'FIFO_WM_EN[0]'};
            end
            if strcmp(value,'FIFO_EN_1')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','SLV_3_FIFO_EN', 'SLV_2_FIFO_EN', 'SLV_1_FIFO_EN', 'SLV_0_FIFO_EN'};
            end
            if strcmp(value,'FIFO_EN_2')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'ACCEL_FIFO_EN','GYRO_Z_FIFO_EN', 'GYRO_Y_FIFO_EN', 'GYRO_X_FIFO_EN', 'TEMP_FIFO_EN'};
            end
            if strcmp(value,'FIFO_RST')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'FIFO_RESET[4]','FIFO_RESET[3]', 'FIFO_RESET[2]', 'FIFO_RESET[1]', 'FIFO_RESET[0]'};
            end
            if strcmp(value,'FIFO_MODE')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'FIFO_MODE[4]','FIFO_MODE[3]', 'FIFO_MODE[2]', 'FIFO_MODE[1]', 'FIFO_MODE[0]'};
            end
            if strcmp(value,'FIFO_R_W')
                 app.Bits70ListBox.Items = {'FIFO_R_W[7]', 'FIFO_R_W[6]', 'FIFO_R_W[5]', 'FIFO_R_W[4]','FIFO_R_W[3]', 'FIFO_R_W[2]', 'FIFO_R_W[1]', 'FIFO_R_W[0]'};
            end
            if strcmp(value,'FIFO_CFG')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', '-', '-', 'FIFO_CFG'};
            end
            %USER BANK 1 registers
            if strcmp(value,'SELF_TEST_X_GYRO')
                 app.Bits70ListBox.Items = {'XG_ST_DATA[7]', 'XG_ST_DATA[6]', 'XG_ST_DATA[5]', 'XG_ST_DATA[4]','XG_ST_DATA[3]', 'XG_ST_DATA[2]', 'XG_ST_DATA[1]', 'XG_ST_DATA[0]'};
            end
            if strcmp(value,'SELF_TEST_Y_GYRO')
                 app.Bits70ListBox.Items = {'YG_ST_DATA[7]', 'YG_ST_DATA[6]', 'YG_ST_DATA[5]', 'YG_ST_DATA[4]','YG_ST_DATA[3]', 'YG_ST_DATA[2]', 'YG_ST_DATA[1]', 'YG_ST_DATA[0]'};
            end
            if strcmp(value,'SELF_TEST_Z_GYRO')
                 app.Bits70ListBox.Items = {'ZG_ST_DATA[7]', 'ZG_ST_DATA[6]', 'ZG_ST_DATA[5]', 'ZG_ST_DATA[4]','ZG_ST_DATA[3]', 'ZG_ST_DATA[2]', 'ZG_ST_DATA[1]', 'ZG_ST_DATA[0]'};
            end
            if strcmp(value,'SELF_TEST_X_ACCEL')
                 app.Bits70ListBox.Items = {'XA_ST_DATA[7]', 'XA_ST_DATA[6]', 'XA_ST_DATA[5]', 'XA_ST_DATA[4]','XA_ST_DATA[3]', 'XA_ST_DATA[2]', 'XA_ST_DATA[1]', 'XA_ST_DATA[0]'};
            end
            if strcmp(value,'SELF_TEST_Y_ACCEL')
                 app.Bits70ListBox.Items = {'YA_ST_DATA[7]', 'YA_ST_DATA[6]', 'YA_ST_DATA[5]', 'YA_ST_DATA[4]','YA_ST_DATA[3]', 'YA_ST_DATA[2]', 'YA_ST_DATA[1]', 'YA_ST_DATA[0]'};
            end
            if strcmp(value,'SELF_TEST_Z_ACCEL')
                 app.Bits70ListBox.Items = {'ZA_ST_DATA[7]', 'ZA_ST_DATA[6]', 'ZA_ST_DATA[5]', 'ZA_ST_DATA[4]','ZA_ST_DATA[3]', 'ZA_ST_DATA[2]', 'ZA_ST_DATA[1]', 'ZA_ST_DATA[0]'};
            end
            if strcmp(value,'XA_OFFS_H')
                 app.Bits70ListBox.Items = {'XA_OFFS[14]', 'XA_OFFS[13]', 'XA_OFFS[12]', 'XA_OFFS[11]','XA_OFFS[10]', 'XA_OFFS[9]', 'XA_OFFS[8]', 'XA_OFFS[7]'};
            end
            if strcmp(value,'XA_OFFS_L')
                 app.Bits70ListBox.Items = {'XA_OFFS[6]', 'XA_OFFS[5]', 'XA_OFFS[4]', 'XA_OFFS[3]','XA_OFFS[2]', 'XA_OFFS[1]', 'XA_OFFS[0]', '-'};
            end
            if strcmp(value,'YA_OFFS_H')
                 app.Bits70ListBox.Items = {'YA_OFFS[14]', 'YA_OFFS[13]', 'YA_OFFS[12]', 'YA_OFFS[11]','YA_OFFS[10]', 'YA_OFFS[9]', 'YA_OFFS[8]', 'YA_OFFS[7]'};
            end
            if strcmp(value,'YA_OFFS_L')
                 app.Bits70ListBox.Items = {'YA_OFFS[6]', 'YA_OFFS[5]', 'YA_OFFS[4]', 'YA_OFFS[3]','YA_OFFS[2]', 'YA_OFFS[1]', 'YA_OFFS[0]', '-'};
            end
            if strcmp(value,'ZA_OFFS_H')
                 app.Bits70ListBox.Items = {'ZA_OFFS[14]', 'ZA_OFFS[13]', 'ZA_OFFS[12]', 'ZA_OFFS[11]','ZA_OFFS[10]', 'ZA_OFFS[9]', 'ZA_OFFS[8]', 'ZA_OFFS[7]'};
            end
            if strcmp(value,'ZA_OFFS_L')
                 app.Bits70ListBox.Items = {'ZA_OFFS[6]', 'ZA_OFFS[5]', 'ZA_OFFS[4]', 'ZA_OFFS[3]','ZA_OFFS[2]', 'ZA_OFFS[1]', 'ZA_OFFS[0]', '-'};
            end
            if strcmp(value,'TIMEBASE_CORRECTION_PLL')
                 app.Bits70ListBox.Items = {'TBC_PLL[7]', 'TBC_PLL[6]', 'TBC_PLL[5]', 'TBC_PLL[4]','TBC_PLL[3]', 'TBC_PLL[2]', 'TBC_PLL[1]', 'TBC_PLL[0]'};
            end
            %USER BANK 2 registers
            if strcmp(value,'GYRO_SMPLRT_DIV')
                 app.Bits70ListBox.Items = {'GYRO_SMPLRT_DIV[7]', 'GYRO_SMPLRT_DIV[6]', 'GYRO_SMPLRT_DIV[5]', 'GYRO_SMPLRT_DIV[4]','GYRO_SMPLRT_DIV[3]', 'GYRO_SMPLRT_DIV[2]', 'GYRO_SMPLRT_DIV[1]', 'GYRO_SMPLRT_DIV[0]'};
            end
            if strcmp(value,'GYRO_CONFIG_1')
                 app.Bits70ListBox.Items = {'-', '-', 'GYRO_DLPFCFG[2]', 'GYRO_DLPFCFG[1]','GYRO_DLPFCFG[0]', 'GYRO_FS_SEL[1]', 'GYRO_FS_SEL[0]', 'GYRO_FCHOICE'};
            end
            if strcmp(value,'GYRO_CONFIG_2')
                 app.Bits70ListBox.Items = {'-', '-', 'XGYRO_CTEN', 'YGYRO_CTEN','ZGYRO_CTEN', 'GYRO_AVGCFG[2]', 'GYRO_AVGCFG[1]', 'GYRO_AVGCFG[0]'};
            end
            if strcmp(value,'XG_OFFS_USRH')
                 app.Bits70ListBox.Items = {'X_OFFS_USER[15]','X_OFFS_USER[14]', 'X_OFFS_USER[13]', 'X_OFFS_USER[12]', 'X_OFFS_USER[11]','X_OFFS_USER[10]', 'X_OFFS_USER[9]', 'X_OFFS_USER[8]'};
            end
            if strcmp(value,'XG_OFFS_USRL')
                 app.Bits70ListBox.Items = {'X_OFFS_USER[7]', 'X_OFFS_USER[6]', 'X_OFFS_USER[5]', 'X_OFFS_USER[4]', 'X_OFFS_USER[3]','X_OFFS_USER[2]', 'X_OFFS_USER[1]', 'X_OFFS_USER[0]'};
            end
            if strcmp(value,'YG_OFFS_USRH')
                 app.Bits70ListBox.Items = {'Y_OFFS_USER[15]','Y_OFFS_USER[14]', 'Y_OFFS_USER[13]', 'Y_OFFS_USER[12]', 'Y_OFFS_USER[11]','Y_OFFS_USER[10]', 'Y_OFFS_USER[9]', 'Y_OFFS_USER[8]'};
            end
            if strcmp(value,'YG_OFFS_USRL')
                 app.Bits70ListBox.Items = {'Y_OFFS_USER[7]', 'Y_OFFS_USER[6]', 'Y_OFFS_USER[5]', 'Y_OFFS_USER[4]', 'Y_OFFS_USER[3]','Y_OFFS_USER[2]', 'Y_OFFS_USER[1]', 'Y_OFFS_USER[0]'};
            end
            if strcmp(value,'ZG_OFFS_USRH')
                 app.Bits70ListBox.Items = {'Z_OFFS_USER[15]','Z_OFFS_USER[14]', 'Z_OFFS_USER[13]', 'Z_OFFS_USER[12]', 'Z_OFFS_USER[11]','Z_OFFS_USER[10]', 'Z_OFFS_USER[9]', 'Z_OFFS_USER[8]'};
            end
            if strcmp(value,'ZG_OFFS_USRL')
                 app.Bits70ListBox.Items = {'Z_OFFS_USER[7]', 'Z_OFFS_USER[6]', 'Z_OFFS_USER[5]', 'Z_OFFS_USER[4]', 'Z_OFFS_USER[3]','Z_OFFS_USER[2]', 'Z_OFFS_USER[1]', 'Z_OFFS_USER[0]'};
            end
            if strcmp(value,'ODR_ALIGN_EN')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', '-', '-', 'ODR_ALIGN_EN'};
            end
            if strcmp(value,'ACCEL_SMPLRT_DIV_1')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','ACCEL_SMPLRT_DIV[11]', 'ACCEL_SMPLRT_DIV[10]', 'ACCEL_SMPLRT_DIV[9]', 'ACCEL_SMPLRT_DIV[8]'};
            end
            if strcmp(value,'ACCEL_SMPLRT_DIV_2')
                 app.Bits70ListBox.Items = {'ACCEL_SMPLRT_DIV[7]', 'ACCEL_SMPLRT_DIV[6]', 'ACCEL_SMPLRT_DIV[5]', 'ACCEL_SMPLRT_DIV[4]', 'ACCEL_SMPLRT_DIV[3]','ACCEL_SMPLRT_DIV[2]', 'ACCEL_SMPLRT_DIV[1]', 'ACCEL_SMPLRT_DIV[0]'};
            end
            if strcmp(value,'ACCEL_INTEL_CTRL')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', '-', 'ACCEL_INTEL_EN', 'ACCEL_INTEL_MODE_INT'};
            end
            if strcmp(value,'ACCEL_WOM_THR')
                 app.Bits70ListBox.Items = {'WOM_THRESHOLD[7]', 'WOM_THRESHOLD[6]', 'WOM_THRESHOLD[5]', 'WOM_THRESHOLD[4]', 'WOM_THRESHOLD[3]','WOM_THRESHOLD[2]', 'WOM_THRESHOLD[1]', 'WOM_THRESHOLD[0]'};
            end
            if strcmp(value,'ACCEL_CONFIG_1')
                 app.Bits70ListBox.Items = {'-', '-', 'ACCEL_DLPFCFG[2]', 'ACCEL_DLPFCFG[1]', 'ACCEL_DLPFCFG[0]','ACCEL_FS_SEL[1]', 'ACCEL_FS_SEL[0]', 'ACCEL_FCHOICE'};
            end
            if strcmp(value,'ACCEL_CONFIG_2')
                 app.Bits70ListBox.Items = {'-', '-', '-', 'AX_ST_EN_REG', 'AY_ST_EN_REG','AZ_ST_EN_REG', 'DEC3_CFG[1]', 'DEC3_CFG[0]'};
            end
            if strcmp(value,'FSYNC_CONFIG')
                 app.Bits70ListBox.Items = {'DELAY_TIME_EN', '-', 'WOF_DEGLITCH_EN', 'WOF_EDGE_INT', 'EXT_SYNC_SET[3]','EXT_SYNC_SET[2]', 'EXT_SYNC_SET[1]', 'EXT_SYNC_SET[0]'};
            end
            if strcmp(value,'TEMP_CONFIG')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', 'TEMP_DLPFCFG[2]', 'TEMP_DLPFCFG[1]', 'TEMP_DLPFCFG[0]'};
            end
            if strcmp(value,'MOD_CTRL_USR')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','-', '-', '-', 'REG_LP_DMP_EN'};
            end
            %USER BANK 3 registers
            if strcmp(value,'I2C_MST_ODR_CONFIG')
                 app.Bits70ListBox.Items = {'-', '-', '-', '-','I2C_MST_ODR_CONFIG[3]', 'I2C_MST_ODR_CONFIG[2]', 'I2C_MST_ODR_CONFIG[1]', 'I2C_MST_ODR_CONFIG[0]'};
            end
            if strcmp(value,'I2C_MST_CTRL')
                 app.Bits70ListBox.Items = {'MULT_MST_EN', '-', '-', 'I2C_MST_P_NSR','I2C_MST_CLK[3]', 'I2C_MST_CLK[2]', 'I2C_MST_CLK[1]', 'I2C_MST_CLK[0]'};
            end
            if strcmp(value,'I2C_MST_DELAY_CTRL')
                 app.Bits70ListBox.Items = {'DELAY_ES_SHADOW', '-', '-', 'I2C_SLV4_DELAY_EN','I2C_SLV3_DELAY_EN', 'I2C_SLV2_DELAY_EN', 'I2C_SLV1_DELAY_EN', 'I2C_SLV0_DELAY_EN'};
            end
            if strcmp(value,'I2C_SLV0_ADDR')
                 app.Bits70ListBox.Items = {'I2C_SLV0_RNW', 'I2C_ID_0[6]', 'I2C_ID_0[5]', 'I2C_ID_0[4]', 'I2C_ID_0[3]','I2C_ID_0[2]', 'I2C_ID_0[1]', 'I2C_ID_0[0]'};
            end
            if strcmp(value,'I2C_SLV0_REG')
                 app.Bits70ListBox.Items = {'I2C_SLV0_REG[7]', 'I2C_SLV0_REG[6]', 'I2C_SLV0_REG[5]', 'I2C_SLV0_REG[4]', 'I2C_SLV0_REG[3]','I2C_SLV0_REG[2]', 'I2C_SLV0_REG[1]', 'I2C_SLV0_REG[0]'};
            end
            if strcmp(value,'I2C_SLV0_CTRL')
                 app.Bits70ListBox.Items = {'I2C_SLV0_EN', 'I2C_SLV0_BYTE_SW', 'I2C_SLV0_REG_DIS', 'I2C_SLV0_GRP','I2C_SLV0_LENG[3]', 'I2C_SLV0_LENG[2]', 'I2C_SLV0_LENG[1]', 'I2C_SLV0_LENG[0]'};
            end
            if strcmp(value,'I2C_SLV0_DO')
                 app.Bits70ListBox.Items = {'I2C_SLV0_DO[7]', 'I2C_SLV0_DO[6]', 'I2C_SLV0_DO[5]', 'I2C_SLV0_DO[4]', 'I2C_SLV0_DO[3]','I2C_SLV0_DO[2]', 'I2C_SLV0_DO[1]', 'I2C_SLV0_DO[0]'};
            end
            if strcmp(value,'I2C_SLV1_ADDR')
                 app.Bits70ListBox.Items = {'I2C_SLV1_RNW', 'I2C_ID_1[6]', 'I2C_ID_1[5]', 'I2C_ID_1[4]', 'I2C_ID_1[3]','I2C_ID_1[2]', 'I2C_ID_1[1]', 'I2C_ID_1[0]'};
            end
            if strcmp(value,'I2C_SLV1_REG')
                 app.Bits70ListBox.Items = {'I2C_SLV1_REG[7]', 'I2C_SLV1_REG[6]', 'I2C_SLV1_REG[5]', 'I2C_SLV1_REG[4]', 'I2C_SLV1_REG[3]','I2C_SLV1_REG[2]', 'I2C_SLV1_REG[1]', 'I2C_SLV1_REG[0]'};
            end
            if strcmp(value,'I2C_SLV1_CTRL')
                 app.Bits70ListBox.Items = {'I2C_SLV1_EN', 'I2C_SLV1_BYTE_SW', 'I2C_SLV1_REG_DIS', 'I2C_SLV1_GRP','I2C_SLV1_LENG[3]', 'I2C_SLV1_LENG[2]', 'I2C_SLV1_LENG[1]', 'I2C_SLV1_LENG[0]'};
            end
            if strcmp(value,'I2C_SLV1_DO')
                 app.Bits70ListBox.Items = {'I2C_SLV1_DO[7]', 'I2C_SLV1_DO[6]', 'I2C_SLV1_DO[5]', 'I2C_SLV1_DO[4]', 'I2C_SLV1_DO[3]','I2C_SLV1_DO[2]', 'I2C_SLV1_DO[1]', 'I2C_SLV1_DO[0]'};
            end
            if strcmp(value,'I2C_SLV2_ADDR')
                 app.Bits70ListBox.Items = {'I2C_SLV2_RNW', 'I2C_ID_2[6]', 'I2C_ID_2[5]', 'I2C_ID_2[4]', 'I2C_ID_2[3]','I2C_ID_2[2]', 'I2C_ID_2[1]', 'I2C_ID_2[0]'};
            end
            if strcmp(value,'I2C_SLV2_REG')
                 app.Bits70ListBox.Items = {'I2C_SLV2_REG[7]', 'I2C_SLV2_REG[6]', 'I2C_SLV2_REG[5]', 'I2C_SLV2_REG[4]', 'I2C_SLV2_REG[3]','I2C_SLV2_REG[2]', 'I2C_SLV2_REG[1]', 'I2C_SLV2_REG[0]'};
            end
            if strcmp(value,'I2C_SLV2_CTRL')
                 app.Bits70ListBox.Items = {'I2C_SLV2_EN', 'I2C_SLV2_BYTE_SW', 'I2C_SLV2_REG_DIS', 'I2C_SLV2_GRP','I2C_SLV2_LENG[3]', 'I2C_SLV2_LENG[2]', 'I2C_SLV2_LENG[1]', 'I2C_SLV2_LENG[0]'};
            end
            if strcmp(value,'I2C_SLV2_DO')
                 app.Bits70ListBox.Items = {'I2C_SLV2_DO[7]', 'I2C_SLV2_DO[6]', 'I2C_SLV2_DO[5]', 'I2C_SLV2_DO[4]', 'I2C_SLV2_DO[3]','I2C_SLV2_DO[2]', 'I2C_SLV2_DO[1]', 'I2C_SLV2_DO[0]'};
            end
            if strcmp(value,'I2C_SLV3_ADDR')
                 app.Bits70ListBox.Items = {'I2C_SLV3_RNW', 'I2C_ID_3[6]', 'I2C_ID_3[5]', 'I2C_ID_3[4]', 'I2C_ID_3[3]','I2C_ID_3[2]', 'I2C_ID_3[1]', 'I2C_ID_3[0]'};
            end
            if strcmp(value,'I2C_SLV3_REG')
                 app.Bits70ListBox.Items = {'I2C_SLV3_REG[7]', 'I2C_SLV3_REG[6]', 'I2C_SLV3_REG[5]', 'I2C_SLV3_REG[4]', 'I2C_SLV3_REG[3]','I2C_SLV3_REG[2]', 'I2C_SLV3_REG[1]', 'I2C_SLV3_REG[0]'};
            end
            if strcmp(value,'I2C_SLV3_CTRL')
                 app.Bits70ListBox.Items = {'I2C_SLV3_EN', 'I2C_SLV3_BYTE_SW', 'I2C_SLV3_REG_DIS', 'I2C_SLV3_GRP','I2C_SLV3_LENG[3]', 'I2C_SLV3_LENG[2]', 'I2C_SLV3_LENG[1]', 'I2C_SLV3_LENG[0]'};
            end
            if strcmp(value,'I2C_SLV3_DO')
                 app.Bits70ListBox.Items = {'I2C_SLV3_DO[7]', 'I2C_SLV3_DO[6]', 'I2C_SLV3_DO[5]', 'I2C_SLV3_DO[4]', 'I2C_SLV3_DO[3]','I2C_SLV3_DO[2]', 'I2C_SLV3_DO[1]', 'I2C_SLV3_DO[0]'};
            end
            if strcmp(value,'I2C_SLV4_ADDR')
                 app.Bits70ListBox.Items = {'I2C_SLV4_RNW', 'I2C_ID_4[6]', 'I2C_ID_4[5]', 'I2C_ID_4[4]', 'I2C_ID_4[3]','I2C_ID_4[2]', 'I2C_ID_4[1]', 'I2C_ID_4[0]'};
            end
            if strcmp(value,'I2C_SLV4_REG')
                 app.Bits70ListBox.Items = {'I2C_SLV4_REG[7]', 'I2C_SLV4_REG[6]', 'I2C_SLV4_REG[5]', 'I2C_SLV4_REG[4]', 'I2C_SLV4_REG[3]','I2C_SLV4_REG[2]', 'I2C_SLV4_REG[1]', 'I2C_SLV4_REG[0]'};
            end
            if strcmp(value,'I2C_SLV4_CTRL')
                 app.Bits70ListBox.Items = {'I2C_SLV4_EN', 'I2C_SLV4_BYTE_SW', 'I2C_SLV4_REG_DIS', 'I2C_SLV4_DLY[4]','I2C_SLV4_DLY[3]', 'I2C_SLV4_DLY[2]', 'I2C_SLV4_DLY[1]', 'I2C_SLV4_DLY[0]'};
            end
            if strcmp(value,'I2C_SLV4_DO')
                 app.Bits70ListBox.Items = {'I2C_SLV4_DO[7]', 'I2C_SLV4_DO[6]', 'I2C_SLV4_DO[5]', 'I2C_SLV4_DO[4]', 'I2C_SLV4_DO[3]','I2C_SLV4_DO[2]', 'I2C_SLV4_DO[1]', 'I2C_SLV4_DO[0]'};
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {554, 554};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RegistersButtonGroup = uibuttongroup(app.LeftPanel);
            app.RegistersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RegisterSelected, true);
            app.RegistersButtonGroup.Title = 'Registers';
            app.EN_INT1Button = uitogglebutton(app.RegistersButtonGroup);
            app.EN_INT1Button.Text = 'EN_INT1';
            app.EN_INT1Button.Value = true;
            app.EN_INT2Button = uitogglebutton(app.RegistersButtonGroup);
            app.EN_INT2Button.Text = 'EN_INT2';
            app.MNGR_INTButton = uitogglebutton(app.RegistersButtonGroup);
            app.MNGR_INTButton.Text = 'MNGR_INT';
            app.CNFG_GENButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_GENButton.Text = 'CNFG_GEN';
            app.MNGR_DYNButton = uitogglebutton(app.RegistersButtonGroup);
            app.MNGR_DYNButton.Text = 'MNGR_DYN';
            app.CNFG_CALButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_CALButton.Text = 'CNFG_CAL';
            app.CNFG_EMUXButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_EMUXButton.Text = 'CNFG_EMUX';
            app.CNFG_ECGButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_ECGButton.Text = 'CNFG_ECG';
            app.CNFG_BMUXButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_BMUXButton.Text = 'CNFG_BMUX';
            app.CNFG_BIOZButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_BIOZButton.Text = 'CNFG_BIOZ';
            app.CNFG_PACEButton = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_PACEButton.Text = 'CNFG_PACE';
            app.CNFG_RTOR1Button = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_RTOR1Button.Text = 'CNFG_RTOR1';
            app.CNFG_RTOR2Button = uitogglebutton(app.RegistersButtonGroup);
            app.CNFG_RTOR2Button.Text = 'CNFG_RTOR2';
            app.ICselectButtonGroup = uibuttongroup(app.LeftPanel);
            app.ICselectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IC_SelectionChanged, true);
            app.ICselectButtonGroup.Title = 'IC select';
            app.MAX30001Button = uiradiobutton(app.ICselectButtonGroup);
            app.MAX30001Button.Text = 'MAX30001';
            app.MAX30001Button.Value = true;
            app.ICM20948Button = uiradiobutton(app.ICselectButtonGroup);
            app.ICM20948Button.Text = 'ICM-20948';
            app.ICM20948UserbankselectButtonGroup = uibuttongroup(app.LeftPanel);
            app.ICM20948UserbankselectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UserBankChanged, true);
            app.ICM20948UserbankselectButtonGroup.Title = 'ICM-20948 User bank select';
            app.ICM20948UserbankselectButtonGroup.Visible = 'off';
            app.USERBANK0Button = uitogglebutton(app.ICM20948UserbankselectButtonGroup);
            app.USERBANK0Button.Text = 'USER BANK 0';
            app.USERBANK0Button.Value = true;
            app.USERBANK1Button = uitogglebutton(app.ICM20948UserbankselectButtonGroup);
            app.USERBANK1Button.Text = 'USER BANK 1';
            app.USERBANK2Button = uitogglebutton(app.ICM20948UserbankselectButtonGroup);
            app.USERBANK2Button.Text = 'USER BANK 2';
            app.USERBANK3Button = uitogglebutton(app.ICM20948UserbankselectButtonGroup);
            app.USERBANK3Button.Text = 'USER BANK 3';
            app.RegistersListBoxLabel = uilabel(app.LeftPanel);
            app.RegistersListBoxLabel.Visible = 'off';
            app.RegistersListBoxLabel.Text = 'Registers';
            app.RegistersListBox = uilistbox(app.LeftPanel);
            app.RegistersListBox.Items = {'USER_CTRL', 'LP_CONFIG', 'PWR_MGMT_1', 'PWR_MGMT_2', 'INT_PIN_CFG', 'INT_ENABLE', 'INT_ENABLE_1', 'INT_ENABLE_2', 'INT_ENABLE_3', 'FIFO_EN_1', 'FIFO_EN_2', 'FIFO_RST', 'FIFO_MODE', 'FIFO_R_W', 'FIFO_CFG'};
            app.RegistersListBox.ValueChangedFcn = createCallbackFcn(app, @ICMRegisterSelected, true);
            app.RegistersListBox.Visible = 'off';
            app.RegistersListBox.Value = 'USER_CTRL';
            app.RightPanel = uipanel(app.GridLayout);
            app.HardwarePanel = uipanel(app.RightPanel);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.BCon = uibutton(app.RightPanel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.RightPanel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.QuickcommandsDropDownLabel = uilabel(app.RightPanel);
            app.QuickcommandsDropDownLabel.Text = 'Quick commands';
            app.QuickcommandsDropDown = uidropdown(app.RightPanel);
            app.QuickcommandsDropDown.Items = {'SW reset', 'Synchronize', 'FIFO reset', 'Read STATUS register', 'Stop ECG', 'Stop BIOZ', 'Stop RtoR', 'Start ECG', 'Start BIOZ', 'Start RtoR', 'ECG Output Mode', 'BioZ Output Mode', 'RtoR Output Mode'};
            app.QuickcommandsDropDown.ValueChangedFcn = createCallbackFcn(app, @command, true);
            app.QuickcommandsDropDown.Value = 'SW reset';
            app.SendButton = uibutton(app.RightPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendB, true);
            app.SendButton.Text = 'Send';
            app.Bits2419ListBoxLabel = uilabel(app.RightPanel);
            app.Bits2419ListBoxLabel.Text = 'Bits 23-16';
            app.Bits2316ListBox = uilistbox(app.RightPanel);
            app.Bits2316ListBox.Items = {'EN_EINT', 'EN_EOVF', 'EN_FSTINT', 'EN_DCLOFFINT', 'EN_BINT', 'EN_BOVF', 'EN_BOVER', 'EN_BUNDR'};
            app.Bits2316ListBox.Multiselect = 'on';
            app.Bits2316ListBox.ValueChangedFcn = createCallbackFcn(app, @RegisterBitsUpdate, true);
            app.Bits2316ListBox.Value = {'EN_EOVF'};
            app.CheckBox24 = uicheckbox(app.RightPanel);
            app.CheckBox24.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox24.Text = '';
            app.CheckBox23 = uicheckbox(app.RightPanel);
            app.CheckBox23.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox23.Text = '';
            app.CheckBox22 = uicheckbox(app.RightPanel);
            app.CheckBox22.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox22.Text = '';
            app.NumericEditFieldLabel = uilabel(app.RightPanel);
            app.NumericEditFieldLabel.Text = 'Numeric';
            app.NumericEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumericEditField.ValueDisplayFormat = '%.0f';
            app.NumericEditField.Value = 100;
            app.HexEditFieldLabel = uilabel(app.RightPanel);
            app.HexEditFieldLabel.Text = 'Hex';
            app.HexEditField = uieditfield(app.RightPanel, 'text');
            app.SendHex = uibutton(app.RightPanel, 'push');
            app.SendHex.ButtonPushedFcn = createCallbackFcn(app, @SendHexPressed, true);
            app.SendHex.Text = 'Send';
            app.CheckBox21 = uicheckbox(app.RightPanel);
            app.CheckBox21.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox21.Text = '';
            app.CheckBox20 = uicheckbox(app.RightPanel);
            app.CheckBox20.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox20.Text = '';
            app.CheckBox19 = uicheckbox(app.RightPanel);
            app.CheckBox19.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox19.Text = '';
            app.CheckBox18 = uicheckbox(app.RightPanel);
            app.CheckBox18.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox18.Text = '';
            app.CheckBox17 = uicheckbox(app.RightPanel);
            app.CheckBox17.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox17.Text = '';
            app.Bits158ListBox = uilistbox(app.RightPanel);
            app.Bits158ListBox.Items = {'EN_EINT', 'EN_EOVF', 'EN_FSTINT', 'EN_DCLOFFINT', 'EN_BINT', 'EN_BOVF', 'EN_BOVER', 'EN_BUNDR'};
            app.Bits158ListBox.Multiselect = 'on';
            app.Bits158ListBox.Value = {'EN_EOVF'};
            app.Bits158ListBoxLabel = uilabel(app.RightPanel);
            app.Bits158ListBoxLabel.Text = 'Bits 15-8';
            app.Bits70ListBox = uilistbox(app.RightPanel);
            app.Bits70ListBox.Items = {'EN_EINT', 'EN_EOVF', 'EN_FSTINT', 'EN_DCLOFFINT', 'EN_BINT', 'EN_BOVF', 'EN_BOVER', 'EN_BUNDR'};
            app.Bits70ListBox.Multiselect = 'on';
            app.Bits70ListBox.Value = {'EN_EOVF'};
            app.Bits70ListBoxLabe = uilabel(app.RightPanel);
            app.Bits70ListBoxLabe.Text = 'Bits 7-0';
            app.CheckBox16 = uicheckbox(app.RightPanel);
            app.CheckBox16.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox16.Text = '';
            app.CheckBox15 = uicheckbox(app.RightPanel);
            app.CheckBox15.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox15.Text = '';
            app.CheckBox14 = uicheckbox(app.RightPanel);
            app.CheckBox14.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox14.Text = '';
            app.CheckBox13 = uicheckbox(app.RightPanel);
            app.CheckBox13.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox13.Text = '';
            app.CheckBox12 = uicheckbox(app.RightPanel);
            app.CheckBox12.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox12.Text = '';
            app.CheckBox11 = uicheckbox(app.RightPanel);
            app.CheckBox11.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox11.Text = '';
            app.CheckBox10 = uicheckbox(app.RightPanel);
            app.CheckBox10.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox10.Text = '';
            app.CheckBox9 = uicheckbox(app.RightPanel);
            app.CheckBox9.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox9.Text = '';
            app.CheckBox8 = uicheckbox(app.RightPanel);
            app.CheckBox8.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox8.Text = '';
            app.CheckBox7 = uicheckbox(app.RightPanel);
            app.CheckBox7.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox7.Text = '';
            app.CheckBox6 = uicheckbox(app.RightPanel);
            app.CheckBox6.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox6.Text = '';
            app.CheckBox5 = uicheckbox(app.RightPanel);
            app.CheckBox5.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox5.Text = '';
            app.CheckBox4 = uicheckbox(app.RightPanel);
            app.CheckBox4.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox4.Text = '';
            app.CheckBox3 = uicheckbox(app.RightPanel);
            app.CheckBox3.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox3.Text = '';
            app.CheckBox2 = uicheckbox(app.RightPanel);
            app.CheckBox2.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox2.Text = '';
            app.CheckBox1 = uicheckbox(app.RightPanel);
            app.CheckBox1.ValueChangedFcn = createCallbackFcn(app, @RegisterValueUpdate, true);
            app.CheckBox1.Text = '';
            app.ReceivedMessageTextAreaLabel = uilabel(app.RightPanel);
            app.ReceivedMessageTextAreaLabel.Text = 'Received Message';
            app.ReceivedMessageTextArea = uitextarea(app.RightPanel);
            app.ConnectionstatusLampLabel = uilabel(app.RightPanel);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.RightPanel);
            app.ConnectionstatusLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAX30001_ICM20948_config_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end